classdef testing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        parameter2DropDown       matlab.ui.control.DropDown
        parameter2DropDownLabel  matlab.ui.control.Label
        parameter1DropDown       matlab.ui.control.DropDown
        parameter1DropDownLabel  matlab.ui.control.Label
        Button                   matlab.ui.control.Button
        outputDropDown           matlab.ui.control.DropDown
        outputDropDownLabel      matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
           
            P=readtable("histP.csv");
           Q=readtable("histQ.csv");
           A=readtable("histA.csv");
           
            P1=P(8801:9601,:);
            Q1=Q(8801:9601,:);
            A1=A(8801:9601,:);
            pressureData= P1;
            volumeData= Q1;
            areaData= A1;
            %PLOT THE DATA
            plot(pressureData,volumeData,areaData); 
      
             
            OData=readtable("~$output details.xlsx");
            startRow= 2;
            endRow = 117;
            columnIndex = 2;
            extractedData = OData{startRow:endRow,columnIndex};
            app.UITable.Data =extractedData;
            plotData= plot('pressureData,volumeData,areaData),extractedData');
        end
       
    end
        end

        % Value changed function: parameter1DropDown
        function parameter1DropDownValueChanged(app, event)
            selectedParameter1 = app.Parameter1DropDown.Value;
            plotData(app,selectedParameter1,app.Parameter2DropDown.Value);
        end

        % Value changed function: parameter2DropDown
        function parameter2DropDownValueChanged(app, event)
            selectedParameter2 = app.Parameter2DropDown.Value;
            plotData(app,app.Parameter1DropDown.Value,app.selectedParameter2);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.outputDropDownLabel = uilabel(app.UIFigure);
            app.outputDropDownLabel.Text = 'output';
            app.outputDropDown = uidropdown(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.parameter1DropDownLabel = uilabel(app.UIFigure);
            app.parameter1DropDownLabel.Text = 'parameter1';
            app.parameter1DropDown = uidropdown(app.UIFigure);
            app.parameter1DropDown.ValueChangedFcn = createCallbackFcn(app, @parameter1DropDownValueChanged, true);
            app.parameter2DropDownLabel = uilabel(app.UIFigure);
            app.parameter2DropDownLabel.Text = 'parameter2';
            app.parameter2DropDown = uidropdown(app.UIFigure);
            app.parameter2DropDown.ValueChangedFcn = createCallbackFcn(app, @parameter2DropDownValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end